classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TestButton                      matlab.ui.control.Button
        GUIforDigitRecognitionTextAreaLabel  matlab.ui.control.Label
        GUIforDigitRecognitionTextArea  matlab.ui.control.TextArea
        Lamp                            matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_4                          matlab.ui.control.Lamp
        Lamp_5                          matlab.ui.control.Lamp
        Lamp_6                          matlab.ui.control.Lamp
        Lamp_7                          matlab.ui.control.Lamp
        Lamp_8                          matlab.ui.control.Lamp
        Lamp_9                          matlab.ui.control.Lamp
        Lamp_10                         matlab.ui.control.Lamp
        PickanimagetotestButtonGroup    matlab.ui.container.ButtonGroup
        Image1Button                    matlab.ui.control.RadioButton
        Image2Button                    matlab.ui.control.RadioButton
        Image3Button                    matlab.ui.control.RadioButton
        Image4Button                    matlab.ui.control.RadioButton
        Image5Button                    matlab.ui.control.RadioButton
        Image6Button                    matlab.ui.control.RadioButton
        Image7Button                    matlab.ui.control.RadioButton
        Image8Button                    matlab.ui.control.RadioButton
        Image9Button                    matlab.ui.control.RadioButton
        Image10Button                   matlab.ui.control.RadioButton
        TrainButton                     matlab.ui.control.StateButton
        scoreaxes                       matlab.ui.control.UIAxes
        drawableaxes                    matlab.ui.control.UIAxes
    end


    methods (Access = private)

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            global W1 W2 W3;
            global Xs_test drawables;
            
            app.Lamp.Color = 'red';
            app.Lamp_2.Color = 'red';
            app.Lamp_3.Color = 'red';
            app.Lamp_4.Color = 'red';
            app.Lamp_5.Color = 'red';
            app.Lamp_6.Color = 'red';
            app.Lamp_7.Color = 'red';
            app.Lamp_8.Color = 'red';
            app.Lamp_9.Color = 'red';
            app.Lamp_10.Color = 'red';
            
            switch app.PickanimagetotestButtonGroup.SelectedObject
                case app.Image1Button
                    imagesc(app.drawableaxes, drawables(:, :, 1));
                    guess = evaluate(Xs_test(:, :, 1), W1, W2, W3);
                    title(app.drawableaxes, guess);
                case app.Image2Button
                    imagesc(app.drawableaxes, drawables(:, :, 2));
                    guess = evaluate(Xs_test(:, :, 2), W1, W2, W3);
                    title(app.drawableaxes, guess);
                    
                case app.Image3Button
                    imagesc(app.drawableaxes, drawables(:, :, 3));
                    guess = evaluate(Xs_test(:, :, 3), W1, W2, W3);
                    title(app.drawableaxes, guess);
                    
                case app.Image4Button
                    imagesc(app.drawableaxes, drawables(:, :, 4));
                    guess = evaluate(Xs_test(:, :, 4), W1, W2, W3);
                    title(app.drawableaxes, guess);
                    
                case app.Image5Button
                    imagesc(app.drawableaxes, drawables(:, :, 5));
                    guess = evaluate(Xs_test(:, :, 5), W1, W2, W3);
                    title(app.drawableaxes, guess);
                    
                case app.Image6Button
                    imagesc(app.drawableaxes, drawables(:, :, 6));
                    guess = evaluate(Xs_test(:, :, 6), W1, W2, W3);
                    title(app.drawableaxes, guess);
                    
                case app.Image7Button
                    imagesc(app.drawableaxes, drawables(:, :, 7));
                    guess = evaluate(Xs_test(:, :, 7), W1, W2, W3);
                    title(app.drawableaxes, guess);
                    
                case app.Image8Button
                    imagesc(app.drawableaxes, drawables(:, :, 8));
                    guess = evaluate(Xs_test(:, :, 8), W1, W2, W3);
                    title(app.drawableaxes, guess);
                    
                case app.Image9Button
                    imagesc(app.drawableaxes, drawables(:, :, 9));
                    guess = evaluate(Xs_test(:, :, 9), W1, W2, W3);
                    title(app.drawableaxes, guess);
                    
                case app.Image10Button
                    imagesc(app.drawableaxes, drawables(:, :, 10));
                    guess = evaluate(Xs_test(:, :, 10), W1, W2, W3);
                    title(app.drawableaxes, guess);
                    
            end
            
            if guess == 0
                app.Lamp.Color = 'green';
            elseif guess == 1
                app.Lamp_2.Color = 'green';
            elseif guess == 2
                app.Lamp_3.Color = 'green';
            elseif guess == 3
                app.Lamp_4.Color = 'green';
            elseif guess == 4
                app.Lamp_5.Color = 'green';
            elseif guess == 5
                app.Lamp_6.Color = 'green';
            elseif guess == 6
                app.Lamp_7.Color = 'green';
            elseif guess == 7
                app.Lamp_8.Color = 'green';
            elseif guess == 8
                app.Lamp_9.Color = 'green';
            elseif guess == 9
                app.Lamp_10.Color = 'green';
            end
        end

        % Value changed function: TrainButton
        function TrainButtonValueChanged(app, event)
            value = app.TrainButton.Value;
            
            global W1 W2 W3;
            global Xs_test drawables;
            [~, ~, Xs_train, ys_train] = readdata('mnist_train.csv');
            [drawables, labels_test, Xs_test, ~] = readdata('mnist_train.csv');
            
            batchsize = 2;
            eta = 1;
            hiddensize = 64;
            
            W1 = rand(hiddensize, 784) * 2 - 1;
            W2 = rand(hiddensize, hiddensize) * 2 - 1;
            W3 = rand(10, hiddensize) * 2 - 1;
            
            [W1, W2, W3, scores] = scoredsgd(Xs_train, ys_train, batchsize, W1, W2, W3, eta, Xs_test, labels_test, 50, 50);
            
            plot(app.scoreaxes,scores)
            title(app.scoreaxes, 'Score: ' + string(score(W1, W2, W3, Xs_test, labels_test, 6000)));
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.GUIforDigitRecognitionTextAreaLabel = uilabel(app.UIFigure);
            app.GUIforDigitRecognitionTextAreaLabel.Text = 'GUI for Digit Recognition';
            app.GUIforDigitRecognitionTextArea = uitextarea(app.UIFigure);
            app.GUIforDigitRecognitionTextArea.Value = {'First train the network by pressing the "Train Button". Then, pick an new image to test against the network before pressing the "Test Button".'};
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_5 = uilamp(app.UIFigure);
            app.Lamp_6 = uilamp(app.UIFigure);
            app.Lamp_7 = uilamp(app.UIFigure);
            app.Lamp_8 = uilamp(app.UIFigure);
            app.Lamp_9 = uilamp(app.UIFigure);
            app.Lamp_10 = uilamp(app.UIFigure);
            app.PickanimagetotestButtonGroup = uibuttongroup(app.UIFigure);
            app.PickanimagetotestButtonGroup.Title = 'Pick an image to test';
            app.Image1Button = uiradiobutton(app.PickanimagetotestButtonGroup);
            app.Image1Button.Text = 'Image 1';
            app.Image1Button.Value = true;
            app.Image2Button = uiradiobutton(app.PickanimagetotestButtonGroup);
            app.Image2Button.Text = 'Image 2';
            app.Image3Button = uiradiobutton(app.PickanimagetotestButtonGroup);
            app.Image3Button.Text = 'Image 3';
            app.Image4Button = uiradiobutton(app.PickanimagetotestButtonGroup);
            app.Image4Button.Text = 'Image 4';
            app.Image5Button = uiradiobutton(app.PickanimagetotestButtonGroup);
            app.Image5Button.Text = 'Image 5';
            app.Image6Button = uiradiobutton(app.PickanimagetotestButtonGroup);
            app.Image6Button.Text = 'Image 6';
            app.Image7Button = uiradiobutton(app.PickanimagetotestButtonGroup);
            app.Image7Button.Text = 'Image 7';
            app.Image8Button = uiradiobutton(app.PickanimagetotestButtonGroup);
            app.Image8Button.Text = 'Image 8';
            app.Image9Button = uiradiobutton(app.PickanimagetotestButtonGroup);
            app.Image9Button.Text = 'Image 9';
            app.Image10Button = uiradiobutton(app.PickanimagetotestButtonGroup);
            app.Image10Button.Text = 'Image 10';
            app.TrainButton = uibutton(app.UIFigure, 'state');
            app.TrainButton.ValueChangedFcn = createCallbackFcn(app, @TrainButtonValueChanged, true);
            app.TrainButton.Text = 'Train';
            app.scoreaxes = uiaxes(app.UIFigure);
            title(app.scoreaxes, 'Score Graph')
            xlabel(app.scoreaxes, 'X')
            ylabel(app.scoreaxes, 'Y')
            app.drawableaxes = uiaxes(app.UIFigure);
            title(app.drawableaxes, 'Test Image')
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end